classdef MEA_Analysis_Toolbox_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MEA_Analysis_ToolboxUIFigure    matlab.ui.Figure
        FileTab                         matlab.ui.container.TabGroup
        DatamanagementTab               matlab.ui.container.Tab
        FilteranalogdataPanel           matlab.ui.container.Panel
        ApplyfilterButton               matlab.ui.control.Button
        lowEditFieldLabel               matlab.ui.control.Label
        lowEditField                    matlab.ui.control.NumericEditField
        highEditFieldLabel              matlab.ui.control.Label
        highEditField                   matlab.ui.control.NumericEditField
        ExportfiltereddataButton        matlab.ui.control.Button
        CropdataPanel                   matlab.ui.container.Panel
        CropButton                      matlab.ui.control.Button
        RemovefromstartsLabel           matlab.ui.control.Label
        RemovefromstartsEditField       matlab.ui.control.NumericEditField
        RemoveelectrodesPanel           matlab.ui.container.Panel
        electrodeRemovalLabel           matlab.ui.control.Label
        ElectrodestoremoveLabel         matlab.ui.control.Label
        electrodeRemovalList            matlab.ui.control.ListBox
        electrodeRemovalButton          matlab.ui.control.Button
        ImportandexportPanel            matlab.ui.container.Panel
        OpenHDF5fileButton              matlab.ui.control.StateButton
        ExportDataButton                matlab.ui.control.Button
        ExportspikedataButton           matlab.ui.control.Button
        CreateimprovedspikedataButton   matlab.ui.control.Button
        SinglerecordingplotsTab         matlab.ui.container.Tab
        AnalogdataPanel                 matlab.ui.container.Panel
        PDSPanel                        matlab.ui.container.Panel
        MaxvalueLabel                   matlab.ui.control.Label
        PSDMaxvalueEditField            matlab.ui.control.NumericEditField
        PowerSpectralDensityButton      matlab.ui.control.Button
        PerformcrosscorrelationButton   matlab.ui.control.Button
        varianceButton                  matlab.ui.control.Button
        SpikedataPanel                  matlab.ui.container.Panel
        spikecountPanel                 matlab.ui.container.Panel
        deltaEditFieldLabel             matlab.ui.control.Label
        deltaEditField                  matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        thresholdEditFieldLabel         matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        CountspikesButton               matlab.ui.control.StateButton
        MeanfiringrateButton            matlab.ui.control.StateButton
        BargraphwithnumberofspikesineachchannelButton  matlab.ui.control.Button
        RasterplotButton                matlab.ui.control.Button
        maxMFRLabel                     matlab.ui.control.Label
        maxMFREditField                 matlab.ui.control.NumericEditField
        spikessecLabel                  matlab.ui.control.Label
        MultiplerecordingsplotsTab      matlab.ui.container.Tab
        OpenfolderButton                matlab.ui.control.Button
        SpikecutoutTab                  matlab.ui.container.Tab
        SpikecutoutPlot                 matlab.ui.control.UIAxes
        FigureButtonSpike               matlab.ui.control.Button
        PCATab                          matlab.ui.container.Tab
        NumberofcomponentsEditFieldLabel_3  matlab.ui.control.Label
        PCAnumCompEditField             matlab.ui.control.NumericEditField
        OutlierstoremoveListBox_2Label  matlab.ui.control.Label
        outliersListBox                 matlab.ui.control.ListBox
        analogPCAoutlierLabel           matlab.ui.control.Label
        analogPCAButton                 matlab.ui.control.StateButton
        spikecutoutPCAButton            matlab.ui.control.StateButton
        ToolConnectTab                  matlab.ui.container.Tab
        ExportToolconnectButton         matlab.ui.control.Button
        ImportToolconnectButton         matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        RecordinginfoPanel              matlab.ui.container.Panel
        DateLabel                       matlab.ui.control.Label
        DurationLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        TimesegmentlengthtoanalyseLabel  matlab.ui.control.Label
        SegmentlengthEditField          matlab.ui.control.NumericEditField
        SamplingrateLabel               matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        AnalogdataLampLabel             matlab.ui.control.Label
        AnalogdataLamp                  matlab.ui.control.Lamp
        SpikedataLampLabel              matlab.ui.control.Label
        SpikedataLamp                   matlab.ui.control.Lamp
        MEAlayoutButtonGroup            matlab.ui.container.ButtonGroup
        chanBtn21                       matlab.ui.control.RadioButton
        chanBtn22                       matlab.ui.control.RadioButton
        chanBtn23                       matlab.ui.control.RadioButton
        chanBtn24                       matlab.ui.control.RadioButton
        chanBtn25                       matlab.ui.control.RadioButton
        chanBtn26                       matlab.ui.control.RadioButton
        chanBtn27                       matlab.ui.control.RadioButton
        chanBtn31                       matlab.ui.control.RadioButton
        chanBtn32                       matlab.ui.control.RadioButton
        chanBtn33                       matlab.ui.control.RadioButton
        chanBtn34                       matlab.ui.control.RadioButton
        chanBtn35                       matlab.ui.control.RadioButton
        chanBtn36                       matlab.ui.control.RadioButton
        chanBtn37                       matlab.ui.control.RadioButton
        chanBtn38                       matlab.ui.control.RadioButton
        chanBtn41                       matlab.ui.control.RadioButton
        chanBtn42                       matlab.ui.control.RadioButton
        chanBtn43                       matlab.ui.control.RadioButton
        chanBtn44                       matlab.ui.control.RadioButton
        chanBtn45                       matlab.ui.control.RadioButton
        chanBtn46                       matlab.ui.control.RadioButton
        chanBtn47                       matlab.ui.control.RadioButton
        chanBtn48                       matlab.ui.control.RadioButton
        chanBtn51                       matlab.ui.control.RadioButton
        chanBtn52                       matlab.ui.control.RadioButton
        chanBtn53                       matlab.ui.control.RadioButton
        chanBtn54                       matlab.ui.control.RadioButton
        chanBtn55                       matlab.ui.control.RadioButton
        chanBtn56                       matlab.ui.control.RadioButton
        chanBtn57                       matlab.ui.control.RadioButton
        chanBtn58                       matlab.ui.control.RadioButton
        chanBtn61                       matlab.ui.control.RadioButton
        chanBtn62                       matlab.ui.control.RadioButton
        chanBtn63                       matlab.ui.control.RadioButton
        chanBtn64                       matlab.ui.control.RadioButton
        chanBtn65                       matlab.ui.control.RadioButton
        chanBtn66                       matlab.ui.control.RadioButton
        chanBtn67                       matlab.ui.control.RadioButton
        chanBtn68                       matlab.ui.control.RadioButton
        chanBtn71                       matlab.ui.control.RadioButton
        chanBtn72                       matlab.ui.control.RadioButton
        chanBtn73                       matlab.ui.control.RadioButton
        chanBtn74                       matlab.ui.control.RadioButton
        chanBtn75                       matlab.ui.control.RadioButton
        chanBtn76                       matlab.ui.control.RadioButton
        chanBtn77                       matlab.ui.control.RadioButton
        chanBtn78                       matlab.ui.control.RadioButton
        chanBtn82                       matlab.ui.control.RadioButton
        chanBtn83                       matlab.ui.control.RadioButton
        chanBtn84                       matlab.ui.control.RadioButton
        chanBtn85                       matlab.ui.control.RadioButton
        chanBtn86                       matlab.ui.control.RadioButton
        chanBtn87                       matlab.ui.control.RadioButton
        chanBtn12                       matlab.ui.control.RadioButton
        chanBtn13                       matlab.ui.control.RadioButton
        chanBtn14                       matlab.ui.control.RadioButton
        chanBtn_ref                     matlab.ui.control.RadioButton
        chanBtn16                       matlab.ui.control.RadioButton
        chanBtn17                       matlab.ui.control.RadioButton
        chanBtn28                       matlab.ui.control.RadioButton
        RawdatavisualizationPanel       matlab.ui.container.Panel
        TsSliderLabel                   matlab.ui.control.Label
        TsSlider                        matlab.ui.control.Slider
        AnalogDataPlot                  matlab.ui.control.UIAxes
        FigureButtonSpike_2             matlab.ui.control.Button
    end


    properties (Access = private)
        fileName            % string with name of open file
        analogLabels        % cell array of labels string from analog recording
        spikeLabels         % cell array of labels string from spike train recording
        spikecutoutLabels   % cell array of labels string from spike cutout recording
        analogData          % McsAnalogStream object with analog data
        spikeCuts           % McsCutoutSegment object with spike cutouts
        spikeData           % McsTimeStampStream object with spike trains
        dataFile            % McsData object: cell array of recordings in the HDF5 file
        selectedChan        % string label with current selected channel 
        recDuration         % double with the total duration of the recording 
        samplingrate        % double with the samplingrate in the recording 
    end

    methods (Access = private)
    
        function plotAnalog(app)
            if ~isempty(app.analogData)
                chanIndex = getLabelIndex({app.selectedChan},app.analogLabels);
                data = app.analogData.ChannelData(chanIndex,:)*1e-6; 
                time = McsHDF5.TickToSec(app.analogData.ChannelDataTimeStamps);
                plot(app.AnalogDataPlot,time,data );
                app.AnalogDataPlot.XLim = [time(1) time(end)];
            end
        end
        
        function plotSpikeCut(app)
            if ~isempty(app.spikeCuts)
                time = 0:1/app.samplingrate*1e3:3;
                if ~isempty(app.spikeCuts.SegmentData{1,1})
                    chanIndex = getLabelIndex({app.selectedChan},app.spikecutoutLabels);
                    data = app.spikeCuts.SegmentData{1,chanIndex}.*1e-6;
                    timestamps = app.spikeCuts.SegmentDataTimeStamps{chanIndex}.*1e-6;
                    
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    tEnd = tStart+app.SegmentlengthEditField.Value;
                    data = data(:,timestamps>=tStart & timestamps<=tEnd);
                    if ~isempty(data)
                        plot(app.SpikecutoutPlot,time, data);
                        hold(app.SpikecutoutPlot,'on')
                        plot(app.SpikecutoutPlot,time,mean(data,2),'-k','LineWidth',2);
                        hold(app.SpikecutoutPlot,'off')
                    else
                        plot(app.SpikecutoutPlot,time,zeros(1,length(time)) );
                        
                    end
                else
                    plot(app.SpikecutoutPlot,time,zeros(1,length(time)) );
                end    
            end
        end

        function loadAnalogData(app)
            if ~isempty(app.fileName)
                if ~isempty(app.dataFile.Recording{1}.AnalogStream)
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    cfg = [];
                    cfg.window = [tStart, tStart + app.SegmentlengthEditField.Value]; % time range in seconds
                
                    app.analogData = app.dataFile.Recording{1}.AnalogStream{1}.readPartialChannelData(cfg);
                    app.analogLabels = app.analogData.Info.Label;
                end
            end
        end
        
        function loadSpikeData(app)
            if ~isempty(app.fileName)
                if ~isempty(app.dataFile.Recording{1}.TimeStampStream)
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    cfg = [];
                    cfg.window = [tStart, app.TsSlider.Value + app.SegmentlengthEditField.Value]; % time range in seconds
                    app.spikeData = app.dataFile.Recording{1}.TimeStampStream{1}.readPartialTimeStampData(cfg);
                    app.spikeLabels = app.spikeData.Info.Label;
                end
            end
        end
        
        function loadSpikeCuts(app)
            if ~isempty(app.fileName)
                if ~ isempty(app.dataFile.Recording{1}.SegmentStream)
                    cfg = [];
                    app.spikeCuts = app.dataFile.Recording{1}.SegmentStream{1}.readPartialSegmentData(cfg);
                    app.spikecutoutLabels = app.spikeCuts.Info.Label;
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Initialize default values
            app.selectedChan = app.MEAlayoutButtonGroup.SelectedObject.Text;
            
            %% Add to path all the subdirectories in the toolbox
            topdir = fileparts(which(mfilename));
            addpath(genpath(topdir));
      
        end

        % Value changed function: analogPCAButton
        function analogPCABtn(app, event)
            if app.analogPCAButton.Value == 1
                if ~ isempty(app.analogData)
                    outlierlist = app.outliersListBox.Value;
                    for i=1:length(outlierlist)
                        if isequal(outlierlist{i},'ref')
                            outlierlist{i} = '15';
                        end
                        outliers(i) = str2num(outlierlist{i});
                    end
                    data = app.analogData.ChannelData';
                    numComponents = app.PCAnumCompEditField.Value;
                    pcaTimeSeries( data,app.analogLabels,outliers,numComponents,app.samplingrate);
                end
                app.analogPCAButton.Value = 0;
            end
        end

        % Button pushed function: 
        % BargraphwithnumberofspikesineachchannelButton
        function bargraphBtn(app, event)
            if ~ isempty(app.spikeData) 
                barGraph( app.spikeData.TimeStamps,app.spikeLabels); 
            end
        end

        % Button pushed function: PerformcrosscorrelationButton
        function crosscorBtn(app, event)
            if ~ isempty(app.analogData)
                data = app.analogData.ChannelData';
                crosscor(data,app.analogLabels);
            end
        end

        % Button pushed function: ExportDataButton
        function export2mat(app, event)
            if ~ isempty(app.analogData)
                % Export raw data to .mat
                data = app.analogData.ChannelData';
                [filename, pathname] = uiputfile('*.mat','Save file as');
                if ~isequal(filename,0) && ~isequal(pathname,0)
                   save([ pathname filename ],'data');
                end
            end
        end

        % Button pushed function: ExportToolconnectButton
        function exportSpiCoDyntBtn(app, event)
            if ~ isempty(app.spikeData)
                % Export to SpiCoDyn format
                date = app.dataFile.Data.Date;
                if contains(date,'?')
                    date = replace(date,'?','_'); 
                end
                tStart = app.TsSlider.Value;
                tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                timestamps = app.spikeData.TimeStamps;
                exportSpiCoDyn(timestamps,app.spikeLabels, tStart,tEnd, app.samplingrate,date)
            end
        end

        % Button pushed function: ImportToolconnectButton
        function importSpiCoDyntBtn(app, event)
            % Import Connectivity matrix from SpiCoDyn end plot graph
            [filename, pathname] = uigetfile('*.txt','Select CM file to plot');
            if ~isequal(filename,0) && ~isequal(pathname,0)
               labels = getLabels();
               cm = load([pathname filename]);
               cfg = [];
               cfg.title = 'Connectivity map - Transfer entropy';
               dirgraph(cm,labels,0,cfg);
            end
        end

        % Value changed function: MeanfiringrateButton
        function mfrBtn(app, event)
            if app.MeanfiringrateButton.Value ==  1
                if ~ isempty(app.spikeData)
                    tStart = app.TsSlider.Value;
                    tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                    maxValue = app.maxMFREditField.Value;
                    plotMFR( app.spikeData.TimeStamps,app.spikeLabels,(tEnd-tStart), maxValue)
                end
                app.MeanfiringrateButton.Value = 0;
            end
        end

        % Value changed function: OpenHDF5fileButton
        function openHDF5Recording(app, event)
            value = app.OpenHDF5fileButton.Value;
            if value
               if isempty(which('McsHDF5.McsData'))
                    warndlg({
                        'Install the library using the Matlab Add-On Explorer,',...
                        'or download from this link:',...
                        'http://www.multichannelsystems.com/software/multi-channel-datamanager'},...
                        'McsMatlabDataTools is not installed!');
                    app.OpenHDF5fileButton.Value = 0;
                    return
                end 
                
                [app.fileName, filePath] = uigetfile('*.h5','Select HDF5 file with recording');
                if app.fileName ~= 0
                    cfg = [];
                    cfg.dataType = 'double'; 
                    try
                        app.dataFile = McsHDF5.McsData([filePath app.fileName],cfg);
                    catch
                        warndlg('This is not a valid Mcs HDF5 file!', 'Read error');
                        app.OpenHDF5fileButton.Value = 0;
                        return
                    end
                    
                    % Update info panel
                    app.Label_4.Text = app.fileName;
                    app.recDuration = double(app.dataFile.Recording{1,1}.Duration)*1e-6;
                    app.Label_2.Text = [num2str(app.recDuration) ' sec'];
                    app.Label_3.Text = app.dataFile.Data.Date;
                    app.MEAlayoutButtonGroup.Title = ['MEA layout: ' app.dataFile.Data.MeaName];
                   
                    % Calculate Sampling rate in Hz
                    if ~isempty(app.dataFile.Recording{1}.AnalogStream)
                        tick = app.dataFile.Recording{1}.AnalogStream{1}.Info.Tick(1);
                        app.samplingrate = 1/(double(tick)*1e-6);
                    elseif ~isempty(app.dataFile.Recording{1}.SegmentStream)
                        tick = app.dataFile.Recording{1}.SegmentStream{1}.SourceInfoChannel.Tick(1);
                        app.samplingrate = 1/(double(tick)*1e-6);
                    else 
                        clear app.dataFile
                        warndlg('File format is incompatible with this toolbox', 'Read error');
                        return
                    end
                    app.Label.Text = [num2str(app.samplingrate) ' Hz'];
                    
                    % Update time segment
                    app.TsSlider.Limits = [0 app.recDuration];
                    if app.SegmentlengthEditField.Value > app.recDuration
                        app.SegmentlengthEditField.Value = app.recDuration;
                    end
                         
                    loadAnalogData(app);
                    loadSpikeCuts(app);
                    loadSpikeData(app);
            
                    if ~ isempty(app.spikeCuts)
                        plotSpikeCut(app);
                    end
                    if ~ isempty(app.analogData)
                        plotAnalog(app);
                        app.AnalogdataLamp = [0.00,1.00,0.00];
                    end
                    if ~ isempty(app.spikeData)
                        app.SpikedataLamp = [0.00,1.00,0.00];
                    end
                end
                app.OpenHDF5fileButton.Value = 0;
            end
            
        end

        % Button pushed function: PowerSpectralDensityButton
        function pdsBtn(app, event)
            if ~ isempty(app.analogData) 
                currentChanIndex = getLabelIndex({app.selectedChan},app.analogLabels);
                data = app.analogData.ChannelData';
                maxValue = app.PSDMaxvalueEditField.Value;
                plotPDS(data(:,currentChanIndex), app.samplingrate, app.selectedChan, maxValue)
            end
        end

        % Button pushed function: FigureButtonSpike_2
        function plotAnalogFigure(app, event)
            if ~isempty(app.analogData)   
                time = McsHDF5.TickToSec(app.analogData.ChannelDataTimeStamps);
                plotAnalogdata(app.analogData.ChannelData',time,app.analogLabels,{app.selectedChan})
            end
        end

        % Button pushed function: FigureButtonSpike
        function plotSpikecutoutsFigure(app, event)
            if ~isempty(app.spikeCuts)
                chanIndex = getLabelIndex({app.selectedChan},app.spikecutoutLabels);
                data = app.spikeCuts.SegmentData{1,chanIndex}.*1e-6;
                timestamps = app.spikeCuts.SegmentDataTimeStamps{chanIndex}.*1e-6;
                
                if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                    tStart = app.recDuration - app.SegmentlengthEditField.Value;
                else
                    tStart = app.TsSlider.Value;
                end
                tEnd = tStart+app.SegmentlengthEditField.Value;
                data = data(:,timestamps>=tStart & timestamps<=tEnd);
                plotSpikecutouts(data,app.selectedChan,app.samplingrate)
                
            end
        end

        % Button pushed function: RasterplotButton
        function rasterplotBtn(app, event)
            if ~ isempty(app.spikeData)
                rasterplot(app.spikeData);
            end
        end

        % Value changed function: SegmentlengthEditField
        function segmentlengthChanged(app, event)
            value = app.SegmentlengthEditField.Value;
            if ~ isempty(app.dataFile)
                if value > app.recDuration
                    app.SegmentlengthEditField.Value = app.recDuration;
                end
                loadAnalogData(app);
                loadSpikeData(app);
                plotAnalog(app);
                plotSpikeCut(app);
            end
            
        end

        % Selection changed function: MEAlayoutButtonGroup
        function selectChan(app, event)
            app.selectedChan = app.MEAlayoutButtonGroup.SelectedObject.Text;
            if ~ isempty(app.dataFile)
                plotSpikeCut(app);
                plotAnalog(app);
            end
      
        end

        % Value changed function: CountspikesButton
        function spikecountBtn(app, event)
            if app.CountspikesButton.Value == 1
                if ~ isempty(app.spikeData)
                    tStart = app.TsSlider.Value;
                    tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                    spikecountConnectivity(app.spikeData.TimeStamps,app.spikeLabels,...
                        app.deltaEditField.Value,(tEnd-tStart),app.thresholdEditField.Value);
                end
                app.CountspikesButton.Value = 0;
            end
        end

        % Value changed function: spikecutoutPCAButton
        function spikecutoutPCABtn(app, event)
            if app.spikecutoutPCAButton.Value == 1
                if ~ isempty(app.spikeCuts)
                    outlierlist = app.outliersListBox.Value;
                    for i=1:length(outlierlist)
                        if isequal(outlierlist{i},'ref')
                            outlierlist{i} = '15';
                        end
                        outliers(i) = str2num(outlierlist{i});
                    end
                    numComponents = app.PCAnumCompEditField.Value;
                    pcaSpikeCutout( app.spikeCuts.SegmentData,app.spikecutoutLabels,outliers,numComponents);
                end
                app.spikecutoutPCAButton.Value = 0;
            end
        end

        % Value changed function: TsSlider
        function timeSlider(app, event)
            if ~ isempty(app.dataFile)
                loadAnalogData(app);
                loadSpikeData(app);
                plotAnalog(app);
                plotSpikeCut(app);
            end
        end

        % Button pushed function: varianceButton
        function varianceBtn(app, event)
            if ~ isempty(app.analogData) 
                data = app.analogData.ChannelData';
                plotVariance(data,app.analogLabels);
            end
        end

        % Button pushed function: ExportspikedataButton
        function ExportspikedataButtonPushed(app, event)
            if ~ isempty(app.spikeData)
                % Export raw data to .mat
                data = app.spikeData.ChannelData';
                [filename, pathname] = uiputfile('*.mat,*.csv','Save file as');
                if ~isequal(filename,0) && ~isequal(pathname,0)
                   save([ pathname filename ],'data');
                end
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            cropLen = app.RemovefromstartsEditField.Value;
            app.analogData
            if ~ isempty(app.analogData)
                plotAnalog(app);
            end
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)
            app.highpassdata = highpassFilter(app.selectedChan,100,app.samplingrate,app.analogData); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MEA_Analysis_ToolboxUIFigure = uifigure;
            app.MEA_Analysis_ToolboxUIFigure.Name = 'MEA Analysis Toolbox';
            app.FileTab = uitabgroup(app.MEA_Analysis_ToolboxUIFigure);
            app.DatamanagementTab = uitab(app.FileTab);
            app.DatamanagementTab.Title = 'Data management';
            app.FilteranalogdataPanel = uipanel(app.DatamanagementTab);
            app.FilteranalogdataPanel.Title = 'Filter analog data';
            app.ApplyfilterButton = uibutton(app.FilteranalogdataPanel, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Enable = 'off';
            app.ApplyfilterButton.Text = 'Apply filter';
            app.lowEditFieldLabel = uilabel(app.FilteranalogdataPanel);
            app.lowEditFieldLabel.Text = 'low';
            app.lowEditField = uieditfield(app.FilteranalogdataPanel, 'numeric');
            app.highEditFieldLabel = uilabel(app.FilteranalogdataPanel);
            app.highEditFieldLabel.Text = 'high';
            app.highEditField = uieditfield(app.FilteranalogdataPanel, 'numeric');
            app.ExportfiltereddataButton = uibutton(app.FilteranalogdataPanel, 'push');
            app.ExportfiltereddataButton.Enable = 'off';
            app.ExportfiltereddataButton.Text = 'Export filtered data';
            app.CropdataPanel = uipanel(app.DatamanagementTab);
            app.CropdataPanel.Title = 'Crop data';
            app.CropButton = uibutton(app.CropdataPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            app.RemovefromstartsLabel = uilabel(app.CropdataPanel);
            app.RemovefromstartsLabel.Text = 'Remove from start [s]';
            app.RemovefromstartsEditField = uieditfield(app.CropdataPanel, 'numeric');
            app.RemoveelectrodesPanel = uipanel(app.DatamanagementTab);
            app.RemoveelectrodesPanel.Title = 'Remove electrodes';
            app.electrodeRemovalLabel = uilabel(app.RemoveelectrodesPanel);
            app.electrodeRemovalLabel.Text = '(hold ctrl to select more)';
            app.ElectrodestoremoveLabel = uilabel(app.RemoveelectrodesPanel);
            app.ElectrodestoremoveLabel.Text = 'Electrodes to remove';
            app.electrodeRemovalList = uilistbox(app.RemoveelectrodesPanel);
            app.electrodeRemovalList.Items = {'12', '13', '14', 'ref', '16', '17', '21', '22', '23', '24', '25', '26', '27', '28', '31', '32', '33', '34', '35', '36', '37', '38', '41', '42', '43', '44', '45', '46', '47', '48', '51', '52', '53', '54', '55', '56', '57', '58', '61', '62', '63', '64', '65', '66', '67', '68', '71', '72', '73', '74', '75', '76', '77', '78', '82', '83', '84', '85', '86', '87'};
            app.electrodeRemovalList.Multiselect = 'on';
            app.electrodeRemovalList.Value = {'ref'};
            app.electrodeRemovalButton = uibutton(app.RemoveelectrodesPanel, 'push');
            app.electrodeRemovalButton.Enable = 'off';
            app.electrodeRemovalButton.Text = 'Remove selected electrodes';
            app.ImportandexportPanel = uipanel(app.DatamanagementTab);
            app.ImportandexportPanel.Title = 'Import and export';
            app.OpenHDF5fileButton = uibutton(app.ImportandexportPanel, 'state');
            app.OpenHDF5fileButton.ValueChangedFcn = createCallbackFcn(app, @openHDF5Recording, true);
            app.OpenHDF5fileButton.Text = 'Open HDF5 file';
            app.ExportDataButton = uibutton(app.ImportandexportPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @export2mat, true);
            app.ExportDataButton.Text = 'Export analog data';
            app.ExportspikedataButton = uibutton(app.ImportandexportPanel, 'push');
            app.ExportspikedataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportspikedataButtonPushed, true);
            app.ExportspikedataButton.Text = 'Export spike data';
            app.CreateimprovedspikedataButton = uibutton(app.ImportandexportPanel, 'push');
            app.CreateimprovedspikedataButton.Enable = 'off';
            app.CreateimprovedspikedataButton.Text = 'Create improved spike data';
            app.SinglerecordingplotsTab = uitab(app.FileTab);
            app.SinglerecordingplotsTab.Title = 'Single recording plots';
            app.AnalogdataPanel = uipanel(app.SinglerecordingplotsTab);
            app.AnalogdataPanel.Title = 'Analog data';
            app.PDSPanel = uipanel(app.AnalogdataPanel);
            app.PDSPanel.Title = 'Plots for selected channels';
            app.MaxvalueLabel = uilabel(app.PDSPanel);
            app.MaxvalueLabel.Text = 'Max value';
            app.PSDMaxvalueEditField = uieditfield(app.PDSPanel, 'numeric');
            app.PSDMaxvalueEditField.Limits = [0 Inf];
            app.PSDMaxvalueEditField.Value = 1000000000;
            app.PowerSpectralDensityButton = uibutton(app.PDSPanel, 'push');
            app.PowerSpectralDensityButton.ButtonPushedFcn = createCallbackFcn(app, @pdsBtn, true);
            app.PowerSpectralDensityButton.Text = 'Power Spectral Density';
            app.PerformcrosscorrelationButton = uibutton(app.AnalogdataPanel, 'push');
            app.PerformcrosscorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @crosscorBtn, true);
            app.PerformcrosscorrelationButton.Text = 'Perform cross correlation';
            app.varianceButton = uibutton(app.AnalogdataPanel, 'push');
            app.varianceButton.ButtonPushedFcn = createCallbackFcn(app, @varianceBtn, true);
            app.varianceButton.Text = 'Variance for each electrode';
            app.SpikedataPanel = uipanel(app.SinglerecordingplotsTab);
            app.SpikedataPanel.Title = 'Spike data';
            app.spikecountPanel = uipanel(app.SpikedataPanel);
            app.spikecountPanel.Title = 'Connectivity map from spike counting';
            app.deltaEditFieldLabel = uilabel(app.spikecountPanel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.spikecountPanel, 'numeric');
            app.deltaEditField.Limits = [0 Inf];
            app.deltaEditField.Value = 100;
            app.sLabel = uilabel(app.spikecountPanel);
            app.sLabel.Text = 'µs';
            app.thresholdEditFieldLabel = uilabel(app.spikecountPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.spikecountPanel, 'numeric');
            app.thresholdEditField.Limits = [0 10];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.Value = 1;
            app.CountspikesButton = uibutton(app.spikecountPanel, 'state');
            app.CountspikesButton.ValueChangedFcn = createCallbackFcn(app, @spikecountBtn, true);
            app.CountspikesButton.Text = 'Count spikes';
            app.MeanfiringrateButton = uibutton(app.SpikedataPanel, 'state');
            app.MeanfiringrateButton.ValueChangedFcn = createCallbackFcn(app, @mfrBtn, true);
            app.MeanfiringrateButton.Text = 'Mean firing rate';
            app.BargraphwithnumberofspikesineachchannelButton = uibutton(app.SpikedataPanel, 'push');
            app.BargraphwithnumberofspikesineachchannelButton.ButtonPushedFcn = createCallbackFcn(app, @bargraphBtn, true);
            app.BargraphwithnumberofspikesineachchannelButton.Text = 'Bargraph with number of spikes in each channel';
            app.RasterplotButton = uibutton(app.SpikedataPanel, 'push');
            app.RasterplotButton.ButtonPushedFcn = createCallbackFcn(app, @rasterplotBtn, true);
            app.RasterplotButton.Text = 'Rasterplot';
            app.maxMFRLabel = uilabel(app.SpikedataPanel);
            app.maxMFRLabel.Text = 'max MFR';
            app.maxMFREditField = uieditfield(app.SpikedataPanel, 'numeric');
            app.maxMFREditField.Limits = [0 Inf];
            app.maxMFREditField.Value = 5;
            app.spikessecLabel = uilabel(app.SpikedataPanel);
            app.spikessecLabel.Text = 'spikes/sec';
            app.MultiplerecordingsplotsTab = uitab(app.FileTab);
            app.MultiplerecordingsplotsTab.Title = 'Multiple recordings plots';
            app.OpenfolderButton = uibutton(app.MultiplerecordingsplotsTab, 'push');
            app.OpenfolderButton.Enable = 'off';
            app.OpenfolderButton.Text = 'Open folder';
            app.SpikecutoutTab = uitab(app.FileTab);
            app.SpikecutoutTab.Title = 'Spike cutout';
            app.SpikecutoutPlot = uiaxes(app.SpikecutoutTab);
            title(app.SpikecutoutPlot, 'Spike Cutout')
            xlabel(app.SpikecutoutPlot, 'Time [ms]')
            ylabel(app.SpikecutoutPlot, 'Voltage [\muV]')
            app.FigureButtonSpike = uibutton(app.SpikecutoutTab, 'push');
            app.FigureButtonSpike.ButtonPushedFcn = createCallbackFcn(app, @plotSpikecutoutsFigure, true);
            app.FigureButtonSpike.Text = 'Open Figure';
            app.PCATab = uitab(app.FileTab);
            app.PCATab.Title = 'PCA';
            app.NumberofcomponentsEditFieldLabel_3 = uilabel(app.PCATab);
            app.NumberofcomponentsEditFieldLabel_3.Text = 'Number of  components';
            app.PCAnumCompEditField = uieditfield(app.PCATab, 'numeric');
            app.PCAnumCompEditField.Limits = [1 59];
            app.PCAnumCompEditField.ValueDisplayFormat = '%.0f';
            app.PCAnumCompEditField.Value = 10;
            app.OutlierstoremoveListBox_2Label = uilabel(app.PCATab);
            app.OutlierstoremoveListBox_2Label.Text = 'Outliers to remove';
            app.outliersListBox = uilistbox(app.PCATab);
            app.outliersListBox.Items = {'12', '13', '14', 'ref', '16', '17', '21', '22', '23', '24', '25', '26', '27', '28', '31', '32', '33', '34', '35', '36', '37', '38', '41', '42', '43', '44', '45', '46', '47', '48', '51', '52', '53', '54', '55', '56', '57', '58', '61', '62', '63', '64', '65', '66', '67', '68', '71', '72', '73', '74', '75', '76', '77', '78', '82', '83', '84', '85', '86', '87'};
            app.outliersListBox.Multiselect = 'on';
            app.outliersListBox.Value = {'ref'};
            app.analogPCAoutlierLabel = uilabel(app.PCATab);
            app.analogPCAoutlierLabel.Text = '(hold ctrl to select more)';
            app.analogPCAButton = uibutton(app.PCATab, 'state');
            app.analogPCAButton.ValueChangedFcn = createCallbackFcn(app, @analogPCABtn, true);
            app.analogPCAButton.Text = 'PCA on analog data';
            app.spikecutoutPCAButton = uibutton(app.PCATab, 'state');
            app.spikecutoutPCAButton.ValueChangedFcn = createCallbackFcn(app, @spikecutoutPCABtn, true);
            app.spikecutoutPCAButton.Text = 'PCA on spike cutouts';
            app.ToolConnectTab = uitab(app.FileTab);
            app.ToolConnectTab.Title = 'ToolConnect';
            app.ExportToolconnectButton = uibutton(app.ToolConnectTab, 'push');
            app.ExportToolconnectButton.ButtonPushedFcn = createCallbackFcn(app, @exportSpiCoDyntBtn, true);
            app.ExportToolconnectButton.Text = 'Export spike train to SpiCoDyn format';
            app.ImportToolconnectButton = uibutton(app.ToolConnectTab, 'push');
            app.ImportToolconnectButton.ButtonPushedFcn = createCallbackFcn(app, @importSpiCoDyntBtn, true);
            app.ImportToolconnectButton.Text = 'Import connectivity matrix from SpiCoDyn and plot connectivity map';
            app.GeneralPanel = uipanel(app.MEA_Analysis_ToolboxUIFigure);
            app.GeneralPanel.Title = 'General';
            app.RecordinginfoPanel = uipanel(app.GeneralPanel);
            app.RecordinginfoPanel.Title = 'Recording info';
            app.DateLabel = uilabel(app.RecordinginfoPanel);
            app.DateLabel.Text = 'Date:';
            app.DurationLabel = uilabel(app.RecordinginfoPanel);
            app.DurationLabel.Text = 'Duration:';
            app.Label = uilabel(app.RecordinginfoPanel);
            app.Label.Text = '';
            app.Label_4 = uilabel(app.RecordinginfoPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.RecordinginfoPanel);
            app.Label_5.Text = '';
            app.FilenameLabel = uilabel(app.RecordinginfoPanel);
            app.FilenameLabel.Text = 'File name:';
            app.secLabel = uilabel(app.RecordinginfoPanel);
            app.secLabel.Text = 'sec';
            app.TimesegmentlengthtoanalyseLabel = uilabel(app.RecordinginfoPanel);
            app.TimesegmentlengthtoanalyseLabel.Text = 'Time segment length to analyse';
            app.SegmentlengthEditField = uieditfield(app.RecordinginfoPanel, 'numeric');
            app.SegmentlengthEditField.Limits = [0 Inf];
            app.SegmentlengthEditField.ValueChangedFcn = createCallbackFcn(app, @segmentlengthChanged, true);
            app.SegmentlengthEditField.Value = 10;
            app.SamplingrateLabel = uilabel(app.RecordinginfoPanel);
            app.SamplingrateLabel.Text = 'Sampling rate:';
            app.Label_6 = uilabel(app.RecordinginfoPanel);
            app.Label_6.Text = '';
            app.AnalogdataLampLabel = uilabel(app.RecordinginfoPanel);
            app.AnalogdataLampLabel.Text = 'Analog data';
            app.AnalogdataLamp = uilamp(app.RecordinginfoPanel);
            app.SpikedataLampLabel = uilabel(app.RecordinginfoPanel);
            app.SpikedataLampLabel.Text = 'Spike data';
            app.SpikedataLamp = uilamp(app.RecordinginfoPanel);
            app.MEAlayoutButtonGroup = uibuttongroup(app.GeneralPanel);
            app.MEAlayoutButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectChan, true);
            app.MEAlayoutButtonGroup.Title = 'MEA layout: 60MEA';
            app.chanBtn21 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn21.Text = '21';
            app.chanBtn22 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn22.Text = '22';
            app.chanBtn23 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn23.Text = '23';
            app.chanBtn24 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn24.Text = '24';
            app.chanBtn25 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn25.Text = '25';
            app.chanBtn26 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn26.Text = '26';
            app.chanBtn27 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn27.Text = '27';
            app.chanBtn31 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn31.Text = '31';
            app.chanBtn32 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn32.Text = '32';
            app.chanBtn33 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn33.Text = '33';
            app.chanBtn34 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn34.Text = '34';
            app.chanBtn35 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn35.Text = '35';
            app.chanBtn36 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn36.Text = '36';
            app.chanBtn37 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn37.Text = '37';
            app.chanBtn38 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn38.Text = '38';
            app.chanBtn41 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn41.Text = '41';
            app.chanBtn42 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn42.Text = '42';
            app.chanBtn43 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn43.Text = '43';
            app.chanBtn44 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn44.Text = '44';
            app.chanBtn45 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn45.Text = '45';
            app.chanBtn45.Value = true;
            app.chanBtn46 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn46.Text = '46';
            app.chanBtn47 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn47.Text = '47';
            app.chanBtn48 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn48.Text = '48';
            app.chanBtn51 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn51.Text = '51';
            app.chanBtn52 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn52.Text = '52';
            app.chanBtn53 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn53.Text = '53';
            app.chanBtn54 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn54.Text = '54';
            app.chanBtn55 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn55.Text = '55';
            app.chanBtn56 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn56.Text = '56';
            app.chanBtn57 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn57.Text = '57';
            app.chanBtn58 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn58.Text = '58';
            app.chanBtn61 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn61.Text = '61';
            app.chanBtn62 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn62.Text = '62';
            app.chanBtn63 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn63.Text = '63';
            app.chanBtn64 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn64.Text = '64';
            app.chanBtn65 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn65.Text = '65';
            app.chanBtn66 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn66.Text = '66';
            app.chanBtn67 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn67.Text = '67';
            app.chanBtn68 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn68.Text = '68';
            app.chanBtn71 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn71.Text = '71';
            app.chanBtn72 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn72.Text = '72';
            app.chanBtn73 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn73.Text = '73';
            app.chanBtn74 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn74.Text = '74';
            app.chanBtn75 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn75.Text = '75';
            app.chanBtn76 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn76.Text = '76';
            app.chanBtn77 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn77.Text = '77';
            app.chanBtn78 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn78.Text = '78';
            app.chanBtn82 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn82.Text = '82';
            app.chanBtn83 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn83.Text = '83';
            app.chanBtn84 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn84.Text = '84';
            app.chanBtn85 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn85.Text = '85';
            app.chanBtn86 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn86.Text = '86';
            app.chanBtn87 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn87.Text = '87';
            app.chanBtn12 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn12.Text = '12';
            app.chanBtn13 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn13.Text = '13';
            app.chanBtn14 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn14.Text = '14';
            app.chanBtn_ref = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn_ref.Text = 'ref';
            app.chanBtn16 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn16.Text = '16';
            app.chanBtn17 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn17.Text = '17';
            app.chanBtn28 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn28.Text = '28';
            app.RawdatavisualizationPanel = uipanel(app.MEA_Analysis_ToolboxUIFigure);
            app.RawdatavisualizationPanel.Title = 'Raw data visualization';
            app.TsSliderLabel = uilabel(app.RawdatavisualizationPanel);
            app.TsSliderLabel.Text = 'T [s]';
            app.TsSlider = uislider(app.RawdatavisualizationPanel);
            app.TsSlider.Limits = [0 1];
            app.TsSlider.ValueChangedFcn = createCallbackFcn(app, @timeSlider, true);
            app.AnalogDataPlot = uiaxes(app.RawdatavisualizationPanel);
            title(app.AnalogDataPlot, 'Analog data')
            xlabel(app.AnalogDataPlot, 'Time [s]')
            ylabel(app.AnalogDataPlot, 'Voltage [\muV]')
            app.FigureButtonSpike_2 = uibutton(app.RawdatavisualizationPanel, 'push');
            app.FigureButtonSpike_2.ButtonPushedFcn = createCallbackFcn(app, @plotAnalogFigure, true);
            app.FigureButtonSpike_2.Text = 'Open Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MEA_Analysis_Toolbox_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MEA_Analysis_ToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MEA_Analysis_ToolboxUIFigure)
        end
    end
end